classdef multi_agent_simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Multi_Agent_Simulation         matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        SelectasimulationLabel         matlab.ui.control.Label
        selectasimulation              matlab.ui.control.DropDown
        SimulationstateEditFieldLabel  matlab.ui.control.Label
        AgentamountEditFieldLabel      matlab.ui.control.Label
        Agentamount                    matlab.ui.control.EditField
        Simulationtime15sLabel         matlab.ui.control.Label
        SimulationTime                 matlab.ui.control.EditField
        Simulationstate                matlab.ui.control.EditField
        Run                            matlab.ui.control.Button
        Reset                          matlab.ui.control.Button
        ControlPanelLabel              matlab.ui.control.Label
        romiLogo                       matlab.ui.control.Image
        EnterD                         matlab.ui.control.Button
        EnterA                         matlab.ui.control.Button
        SelectagraphDropDownLabel      matlab.ui.control.Label
        Selectagraph                   matlab.ui.control.DropDown
        RightPanel                     matlab.ui.container.Panel
        fig2                           matlab.ui.control.UIAxes
        fig4                           matlab.ui.control.UIAxes
        bLabel                         matlab.ui.control.Label
        dLabel                         matlab.ui.control.Label
        aLabel                         matlab.ui.control.Label
        cLabel                         matlab.ui.control.Label
        VisualizationPanelLabel        matlab.ui.control.Label
        fig1                           matlab.ui.control.UIAxes
        fig3                           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        % matices
        Deg                            double
        Adj                            double
        
        % properties for pop-up window
        % Degree matrix
        Dfigure                        matlab.ui.Figure
        D                              matlab.ui.control.Table
        Dcomplete                      matlab.ui.control.Button
        Djudge                         matlab.ui.control.EditField
        
        % Adjacency matrix
        Afigure                        matlab.ui.Figure
        A                              matlab.ui.control.Table
        Acomplete                      matlab.ui.control.Button
        Ajudge                         matlab.ui.control.EditField
        
        % Graphs
        GraphFigure                    matlab.ui.Figure
        Graph                          matlab.ui.control.Image
        
    end
    
    methods (Access = private)
        
        function clearFigure(app)
            LineObjects = findall(app.fig1, 'type', 'line');
            delete(LineObjects);
            textObjects = findall(app.fig1, 'type', 'text');
            delete(textObjects);
            PointObjects = findall(app.fig1, 'type', 'point');
            delete(PointObjects);
            xlabel(app.fig1, '');
            ylabel(app.fig1, '');
            
            LineObjects = findall(app.fig2, 'type', 'line');
            delete(LineObjects);
            textObjects = findall(app.fig2, 'type', 'text');
            delete(textObjects);
            PointObjects = findall(app.fig2, 'type', 'point');
            delete(PointObjects);
            xlabel(app.fig2, '');
            ylabel(app.fig2, '');
            
            LineObjects = findall(app.fig3, 'type', 'line');
            delete(LineObjects);
            PointObjects = findall(app.fig3, 'type', 'point');
            delete(PointObjects);
            xlabel(app.fig3, '');
            ylabel(app.fig3, '');
            
            LineObjects = findall(app.fig4, 'type', 'line');
            delete(LineObjects);
            PointObjects = findall(app.fig4, 'type', 'point');
            delete(PointObjects);
            xlabel(app.fig4, '');
            ylabel(app.fig4, '');
        end
        
        function plotting(app, flag, param, data)
            item = app.selectasimulation.Items;
            n = size(data.t, 2);
            if strcmp(item{2}, flag)
                for i = 1:5:n
                    % figure 1
                    LineObjects = findall(app.fig1, 'type', 'line');
                    delete(LineObjects);
                    textObjects = findall(app.fig1, 'type', 'text');
                    delete(textObjects);
                    PointObjects = findall(app.fig1, 'type', 'point');
                    if size(PointObjects) ~= 0
                        delete(PointObjects(3));
                    end
                    plot(app.fig1, param.ref(1:2:end), param.ref(2:2:end), 'ro');
                    hold(app.fig1, "on");
                    plot(app.fig1, param.x0(1:2:end), param.x0(2:2:end), 'r*');
                    hold(app.fig1, "on");
                    for j = 1:param.N
                        plot(app.fig1, data.x(2*j-1, 1:i), data.x(2*j, 1:i), 'Color', [0 0.4470 0.7410], 'LineWidth', 1);
                        hold(app.fig1, "on");
                        plot(app.fig1, data.x(2*j-1, i), data.x(2*j, i), 'k*', 'LineWidth', 1);
                        text(app.fig1, data.x(2*j-1, i)+0.5, data.x(2*j, i)+0.5, num2str(j));
                        hold(app.fig1, "on");
                    end
                    xlabel(app.fig1, 'x position');
                    ylabel(app.fig1, 'y position');
                    drawnow limitrate nocallbacks;
                    
                    % figure 2
                    LineObjects = findall(app.fig2, 'type', 'line');
                    delete(LineObjects);
                    textObjects = findall(app.fig2, 'type', 'text');
                    delete(textObjects);
                    PointObjects = findall(app.fig2, 'type', 'point');
                    if size(PointObjects) ~= 0
                        delete(PointObjects(3));
                    end
                    plot(app.fig2, param.x0(1:2:end), param.x0(2:2:end), 'r*');
                    hold(app.fig2, "on");
                    for j = 1:size(app.Deg, 1)
                        plot(app.fig2, data.x(2*j-1, 1:i), data.x(2*j, 1:i), 'Color', [0 0.4470 0.7410], 'LineWidth', 1);
                        hold(app.fig2, "on");
                        plot(app.fig2, data.x(2*j-1, i), data.x(2*j, i), 'k*', 'LineWidth', 1);
                        text(app.fig2, data.x(2*j-1, i)+0.5, data.x(2*j, i)+0.5, num2str(j));
                        hold(app.fig2, "on");
                        for k = 1:size(app.Deg, 1)
                            if app.Adj(j,k) ~= 0
                                plot(app.fig2, [param.x0(2*j-1); param.x0(2*k-1)], [param.x0(2*j); param.x0(2*k)], 'LineWidth', 1, 'Color', [0.4660 0.6740 0.1880]);
                                hold(app.fig2, "on");
                                if strcmp(app.Selectagraph.Value, app.Selectagraph.Items{6})
                                    plot(app.fig2, [data.x(2*j-1, i); data.x(2*k-1, i)], [data.x(2*j, i), data.x(2*k, i)], 'LineWidth', 1, 'Color', [0.9290 0.6940 0.1250]);
                                else
                                    plot(app.fig2, [data.x(2*j-1, i); data.x(2*k-1, i)], [data.x(2*j, i), data.x(2*k, i)], 'LineWidth', 1, 'Color', [0.4940 0.1840 0.5560]);
                                end
                                hold(app.fig2, "on");
                            end
                        end
                    end
                    xlabel(app.fig2, 'x position');
                    ylabel(app.fig2, 'y position');
                    drawnow limitrate nocallbacks;
                    
                    % figure 3
                    LineObjects = findall(app.fig3, 'type', 'line');
                    delete(LineObjects);
                    textObjects = findall(app.fig1, 'type', 'text');
                    delete(textObjects);
                    plot(app.fig3, data.t(1:i), data.u(:,1:i), 'LineWidth', 1);
                    xlabel(app.fig3, 'time(sec)');
                    ylabel(app.fig3, 'control');
                    drawnow limitrate nocallbacks;
                    
                    % figure 4
                    LineObjects = findall(app.fig4, 'type', 'line');
                    delete(LineObjects);
                    plot(app.fig4, data.t(1:i), data.er(:,1:i), 'LineWidth', 1);
                    xlabel(app.fig4, 'time(sec)');
                    ylabel(app.fig4, 'error');
                    drawnow limitrate nocallbacks;
                end
            elseif strcmp(item{3}, flag)
                for i = 1:5:n
                    % figure 1
                    LineObjects = findall(app.fig1, 'type', 'line');
                    delete(LineObjects);
                    textObjects = findall(app.fig1, 'type', 'text');
                    delete(textObjects);
                    PointObjects = findall(app.fig1, 'type', 'point');
                    if size(PointObjects) ~= 0
                        delete(PointObjects(3));
                    end
                    plot(app.fig1, param.ref(1:2:end), param.ref(2:2:end), 'ro');
                    hold(app.fig1, "on");
                    plot(app.fig1, param.x0(1:2:end), param.x0(2:2:end), 'r*');
                    hold(app.fig1, "on");
                    for j = 1:param.N
                        plot(app.fig1, data.x(2*j-1, 1:i), data.x(2*j, 1:i),'Color', [0 0.4470 0.7410], 'LineWidth', 1);
                        hold(app.fig1, "on");
                        plot(app.fig1, data.x(2*j-1, i), data.x(2*j, i), 'k*', 'LineWidth', 1);
                        text(app.fig1, data.x(2*j-1, i)+0.5, data.x(2*j, i)+0.5, num2str(j));
                        hold(app.fig1, "on");
                    end
                    plot(app.fig1, param.circle(:, 1), param.circle(:, 2), 'LineWidth', 1, 'Color', [0.8500 0.3250 0.0980]);
                    xlabel(app.fig1, 'x position');
                    ylabel(app.fig1, 'y position');
                    drawnow limitrate nocallbacks;
                    
                    % figure 2
                    LineObjects = findall(app.fig2, 'type', 'line');
                    delete(LineObjects);
                    textObjects = findall(app.fig2, 'type', 'text');
                    delete(textObjects);
                    PointObjects = findall(app.fig2, 'type', 'point');
                    if size(PointObjects) ~= 0
                        delete(PointObjects(3));
                    end
                    plot(app.fig2, param.x0(1:2:end), param.x0(2:2:end), 'r*');
                    hold(app.fig2, "on");
                    for j = 1:size(app.Deg, 1)
                        plot(app.fig2, data.x(2*j-1, 1:i), data.x(2*j, 1:i),'Color', [0 0.4470 0.7410], 'LineWidth', 1);
                        hold(app.fig2, "on");
                        plot(app.fig2, data.x(2*j-1, i), data.x(2*j, i), 'k*', 'LineWidth', 1);
                        text(app.fig2, data.x(2*j-1, i)+0.5, data.x(2*j, i)+0.5, num2str(j));
                        hold(app.fig2, "on");
                        for k = 1:size(app.Deg, 1)
                            if app.Adj(j,k) ~= 0
                                plot(app.fig2, [param.x0(2*j-1); param.x0(2*k-1)], [param.x0(2*j); param.x0(2*k)], 'LineWidth', 1, 'Color', [0.4660 0.6740 0.1880]);
                                hold(app.fig2, "on");
                                if strcmp(app.Selectagraph.Value, app.Selectagraph.Items{6})
                                    plot(app.fig2, [data.x(2*j-1, i); data.x(2*k-1, i)], [data.x(2*j, i), data.x(2*k, i)], 'LineWidth', 1, 'Color', [0.9290 0.6940 0.1250]);
                                else
                                    plot(app.fig2, [data.x(2*j-1, i); data.x(2*k-1, i)], [data.x(2*j, i), data.x(2*k, i)], 'LineWidth', 1, 'Color', [0.4940 0.1840 0.5560]);
                                end
                                hold(app.fig2, "on");
                            end
                        end
                    end
                    xlabel(app.fig2, 'x position');
                    ylabel(app.fig2, 'y position');
                    drawnow limitrate nocallbacks;
                    
                    % figure 3
                    LineObjects = findall(app.fig3, 'type', 'line');
                    delete(LineObjects);
                    plot(app.fig3, data.t(1:i), data.u(:,1:i), 'LineWidth', 1);
                    xlabel(app.fig3, 'time(sec)');
                    ylabel(app.fig3, 'control');
                    drawnow limitrate nocallbacks;
                    
                    % figure 4
                    LineObjects = findall(app.fig4, 'type', 'line');
                    delete(LineObjects);
                    plot(app.fig4, data.t(1:i), data.er(:,1:i), 'LineWidth', 1);
                    xlabel(app.fig4, 'time(sec)');
                    ylabel(app.fig4, 'error');
                    drawnow limitrate nocallbacks;
                end
                
            end
            
        end
        
        
        function DcompletePushed(app, ~)
            erMsg = 'Wrong degree matrix!';
            goodMsg = 'Right matrix. Congrats!';
            
            graphName = app.Selectagraph.Value;
            items = app.Selectagraph.Items;
            
            app.Deg = app.D.Data;
            if strcmp(graphName, items{1})
                delete(app.Dfigure);
            elseif strcmp(graphName, items{7})
                if app.Deg == zeros(5,5)
                    app.Djudge.Value = erMsg;
                    app.Djudge.BackgroundColor = [1, 0, 0];
                elseif app.Deg == app.Deg'
                    app.Djudge.Value = goodMsg;
                    app.Djudge.BackgroundColor = [1, 1, 1];
                    pause(1);
                    delete(app.Dfigure);
                else
                    app.Djudge.Value = erMsg;
                    app.Djudge.BackgroundColor = [1, 0, 0];
                end
            else
                [D_ans, ~] = answers(graphName, items);
                
                if app.Deg == D_ans
                    app.Djudge.Value = goodMsg;
                    app.Djudge.BackgroundColor = [1, 1, 1];
                    pause(1);
                    delete(app.Dfigure);
                else
                    app.Djudge.Value = erMsg;
                    app.Djudge.BackgroundColor = [1, 0, 0];
                end
            end
        end
        
        function AcompletePushed(app, ~)
            erMsg = 'Wrong adjacency matrix!';
            goodMsg = 'Right matrix. Congrats!';
            
            graphName = app.Selectagraph.Value;
            items = app.Selectagraph.Items;
            
            app.Adj = app.A.Data;
            if strcmp(graphName, items{1})
                delete(app.Afigure);
            elseif strcmp(graphName, items{7})
                if app.Adj == zeros(5,5)
                    app.Ajudge.Value = erMsg;
                    app.Ajudge.BackgroundColor = [1, 0, 0];
                    %                 elseif app.Adj == app.Adj'
                    %                     app.Ajudge.Value = goodMsg;
                    %                     app.Ajudge.BackgroundColor = [1, 1, 1];
                else
                    app.Ajudge.Value = goodMsg;
                    pause(1);
                    delete(app.Afigure);
                    %                 else
                    %                     app.Ajudge.Value = erMsg;
                    %                     app.Ajudge.BackgroundColor = [1, 0, 0];
                end
            else
                [~, A_ans] = answers(graphName, items);
                
                if app.Adj == A_ans
                    app.Ajudge.Value = goodMsg;
                    app.Ajudge.BackgroundColor = [1, 1, 1];
                    pause(1);
                    delete(app.Afigure);
                else
                    app.Ajudge.Value = erMsg;
                    app.Ajudge.BackgroundColor = [1, 0, 0];
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Multi_Agent_Simulation.Name = 'Multi-Agent Simulation Interface';
            app.Multi_Agent_Simulation.Position = [250 150 1025 570];
            
            % control panel
            app.ControlPanelLabel.Position = [20 530 135 25];
            
            app.SelectasimulationLabel.Position = [3 490 135 25];
            app.SelectasimulationLabel.FontSize = 14;
            app.selectasimulation.Position = [20 465 191 22];
            app.selectasimulation.Value = app.selectasimulation.Items{1};
            
            app.SelectagraphDropDownLabel.Position = [20 430 135 25];
            app.SelectagraphDropDownLabel.FontSize = 14;
            app.Selectagraph.Position = [20 405 191 22];
            app.Selectagraph.Value = '';
            
            app.AgentamountEditFieldLabel.Position = [9 365 100 25];
            app.AgentamountEditFieldLabel.FontSize = 14;
            app.Agentamount.Position = [20 340 100 22];
            app.Agentamount.Value = '5';
            app.Agentamount.Editable = "off";
            
            app.Simulationtime15sLabel.Position = [10 300 150 25];
            app.Simulationtime15sLabel.FontSize = 14;
            app.SimulationTime.Position = [20 275 150 22];
            app.SimulationTime.Value = '';
            app.Simulationstate.BackgroundColor = [1, 1, 1];
            
            app.SimulationstateEditFieldLabel.Position = [20 235 100 25];
            app.SimulationstateEditFieldLabel.FontSize = 14;
            app.Simulationstate.Position = [20 210 191 22];
            app.Simulationstate.Value = 'Please select a simulation first!';
            app.Simulationstate.BackgroundColor = [1, 1, 1];
            app.Simulationstate.Editable = "off";
            
            app.EnterD.Position = [20 170 75 22];
            app.EnterA.Position = [136 170 75 22];
            
            app.Run.Position = [20 130 75 22];
            app.Reset.Position = [136 130 75 22];
            
            app.romiLogo.Position = [-15 0 150 56];
            
            % visualization panel
            app.VisualizationPanelLabel.Position = [20 530 200 25];
            
            app.fig1.Position = [20 300 350 200];
            app.aLabel.Position = [190 270 25 22];
            
            app.fig2.Position = [400 300 350 200];
            app.bLabel.Position = [575 270 25 22];
            
            app.fig3.Position = [20 70 350 200];
            app.cLabel.Position = [190 40 25 22];
            
            app.fig4.Position = [400 70 350 200];
            app.dLabel.Position = [575 40 25 22];
            
            app.Deg = zeros(5,5);
            app.Adj = zeros(5,5);
            
            % clear pop-up windows
            delete(app.GraphFigure);
            delete(app.Afigure);
            delete(app.Dfigure);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Multi_Agent_Simulation.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {677, 677};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {251, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: Run
        function RunPushed(app, event)
            if app.Deg == zeros(5,5)
                app.Simulationstate.Value = 'Degree matrix needed!';
                app.Simulationstate.BackgroundColor = [1, 0, 0];
            elseif app.Adj == zeros(5,5)
                app.Simulationstate.Value = 'Adjacency matrix needed!';
                app.Simulationstate.BackgroundColor = [1, 0, 0];
            else
                value = app.selectasimulation.Value;
                item = app.selectasimulation.Items;
                N = str2double(app.Agentamount.Value);
                %             k = str2double(app.K.Value);
                L = app.Deg - app.Adj;
                t = str2double(app.SimulationTime.Value);
                dt = 0.01;
                
                greetMsg = 'Please select a simulation first!';
                runMsg = 'Simulation is running.';
                erMsg = 'Simulation time is too long';
                msg = 'Simulation succeeded!';
                
                app.Simulationstate.BackgroundColor = [1, 1, 1];
                
                clearFigure(app);
                
                if strcmp(item{1}, value)
                    app.Simulationstate.Value = greetMsg;
                    app.Simulationstate.BackgroundColor = [1, 1, 1];
                elseif t>15
                    app.Simulationstate.Value = erMsg;
                    app.Simulationstate.BackgroundColor = [1, 0, 0];
                elseif strcmp(item{2}, value)
                    L = kron(L, eye(2));
                    app.Simulationstate.Value = runMsg;
                    [param, data] = consensus(N, L, t, dt);
                    plotting(app, value, param, data);
                    app.Simulationstate.Value = msg;
                    app.Simulationstate.BackgroundColor = [1, 1, 1];
                elseif strcmp(item{3}, value)
                    app.Simulationstate.Value = runMsg;
                    L = kron(L, eye(2));
                    [param, data] = position_based_formation(N, L, t, dt);
                    plotting(app, value, param, data);
                    app.Simulationstate.Value = msg;
                    app.Simulationstate.BackgroundColor = [1, 1, 1];
                end
            end
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            startupFcn(app);
            
            % clear figures
            clearFigure(app);
            
            % delete pop-up windows
            delete(app.Afigure);
            delete(app.Dfigure);
            
            clear;
            close all;
        end

        % Button pushed function: EnterD
        function EnterDPushed(app, event)
            if ~strcmp(app.Selectagraph.Value, app.Selectagraph.Items{1})
                app.Dfigure = uifigure('Visible');
                app.Dfigure.Position = [550, 250, 450, 200];
                app.Dfigure.Name = 'Degree Matrix';
                
                app.D = uitable(app.Dfigure);
                app.D.Data = app.Deg;
                app.D.Position = [15, 47, 420, 136];
                app.D.ColumnEditable = logical(10);
                
                app.Dcomplete = uibutton(app.Dfigure);
                app.Dcomplete.Position = [120, 12, 60, 25];
                app.Dcomplete.Text = 'Complete';
                app.Dcomplete.ButtonPushedFcn = createCallbackFcn(app, @DcompletePushed, true);
                
                app.Djudge = uieditfield(app.Dfigure, 'text');
                app.Djudge.Position = [190, 13.5, 150, 22];
                app.Djudge.Value = 'Please input the entries.';
                app.Djudge.Editable = "off";
            else
                app.Simulationstate.Value = 'No degree matrix required!';
                app.Simulationstate.BackgroundColor = [1, 0, 0];
            end
        end

        % Button pushed function: EnterA
        function EnterAButtonPushed(app, event)
            if ~strcmp(app.Selectagraph.Value, app.Selectagraph.Items{1})
                app.Afigure = uifigure('Visible');
                app.Afigure.Position = [550, 250, 450, 200];
                app.Afigure.Name = 'Adjacency Matrix';
                
                app.A = uitable(app.Afigure);
                app.A.Data = app.Adj;
                app.A.Position = [15, 47, 420, 136];
                app.A.ColumnEditable = logical(5);
                
                app.Acomplete = uibutton(app.Afigure);
                app.Acomplete.Position = [120, 12, 60, 25];
                app.Acomplete.Text = 'Complete';
                app.Acomplete.ButtonPushedFcn = createCallbackFcn(app, @AcompletePushed, true);
                
                app.Ajudge = uieditfield(app.Afigure, 'text');
                app.Ajudge.Position = [190, 13.5, 150, 22];
                app.Ajudge.Value = 'Please input the entries.';
                app.Ajudge.Editable = "off";
            else
                app.Simulationstate.Value = 'No adjacency matrix required!';
                app.Simulationstate.BackgroundColor = [1, 0, 0];
            end
        end

        % Value changed function: Selectagraph
        function SelectagraphValueChanged(app, event)
            % reset
            app.Adj = zeros(5, 5);
            app.Deg = zeros(5, 5);
            
            % action
            value = app.Selectagraph.Value;
            items = app.Selectagraph.Items;
            
            delete(app.GraphFigure);
            app.GraphFigure = uifigure('Visible');
            app.GraphFigure.Position = [550, 250, 320, 320];
            
            switch(value)
                case items{2}
                    app.GraphFigure.Name = items{2};
                    app.Graph = uiimage(app.GraphFigure);
                    app.Graph.Position = [10, 10, 300, 300];
                    app.Graph.ImageSource = 'Graph 1.jpg';
                case items{3}
                    app.GraphFigure.Name = items{3};
                    app.Graph = uiimage(app.GraphFigure);
                    app.Graph.Position = [10, 10, 300, 300];
                    app.Graph.ImageSource = 'Graph 2.jpg';
                case items{4}
                    app.GraphFigure.Name = items{4};
                    app.Graph = uiimage(app.GraphFigure);
                    app.Graph.Position = [10, 10, 300, 300];
                    app.Graph.ImageSource = 'Graph 3.jpg';
                case items{5}
                    app.GraphFigure.Name = items{5};
                    app.Graph = uiimage(app.GraphFigure);
                    app.Graph.Position = [10, 10, 300, 300];
                    app.Graph.ImageSource = 'Graph 4.jpg';
                case items{6}
                    app.GraphFigure.Name = items{6};
                    app.Graph = uiimage(app.GraphFigure);
                    app.Graph.Position = [10, 10, 300, 300];
                    app.Graph.ImageSource = 'Graph 5.jpg';
                otherwise
                    delete(app.GraphFigure);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Multi_Agent_Simulation = uifigure('Visible', 'off');
            app.Multi_Agent_Simulation.Name = 'UI Figure';
            app.Multi_Agent_Simulation.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Multi_Agent_Simulation);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectasimulationLabel = uilabel(app.LeftPanel);
            app.SelectasimulationLabel.Text = 'Select a simulation';
            app.selectasimulation = uidropdown(app.LeftPanel);
            app.selectasimulation.Items = {'', 'Consensus ', 'Position-based formation'};
            app.selectasimulation.Value = '';
            app.SimulationstateEditFieldLabel = uilabel(app.LeftPanel);
            app.SimulationstateEditFieldLabel.Text = 'Simulation state';
            app.AgentamountEditFieldLabel = uilabel(app.LeftPanel);
            app.AgentamountEditFieldLabel.Text = 'Agent amount';
            app.Agentamount = uieditfield(app.LeftPanel, 'text');
            app.Agentamount.Value = '5';
            app.Simulationtime15sLabel = uilabel(app.LeftPanel);
            app.Simulationtime15sLabel.Text = 'Simulation time (<15s)';
            app.SimulationTime = uieditfield(app.LeftPanel, 'text');
            app.Simulationstate = uieditfield(app.LeftPanel, 'text');
            app.Run = uibutton(app.LeftPanel, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @RunPushed, true);
            app.Run.Text = 'Run';
            app.Reset = uibutton(app.LeftPanel, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Reset';
            app.ControlPanelLabel = uilabel(app.LeftPanel);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.romiLogo = uiimage(app.LeftPanel);
            app.romiLogo.ImageSource = 'Romi_Logo_Hori_Colour_Light.jpg';
            app.EnterD = uibutton(app.LeftPanel, 'push');
            app.EnterD.ButtonPushedFcn = createCallbackFcn(app, @EnterDPushed, true);
            app.EnterD.Text = 'Enter D';
            app.EnterA = uibutton(app.LeftPanel, 'push');
            app.EnterA.ButtonPushedFcn = createCallbackFcn(app, @EnterAButtonPushed, true);
            app.EnterA.Text = 'Enter A';
            app.SelectagraphDropDownLabel = uilabel(app.LeftPanel);
            app.SelectagraphDropDownLabel.Text = 'Select a graph';
            app.Selectagraph = uidropdown(app.LeftPanel);
            app.Selectagraph.Items = {'', 'Graph 1', 'Graph 2', 'Graph 3', 'Graph 4', 'Graph 5', 'DIY Graph'};
            app.Selectagraph.ValueChangedFcn = createCallbackFcn(app, @SelectagraphValueChanged, true);
            app.Selectagraph.Value = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.fig2 = uiaxes(app.RightPanel);
            title(app.fig2, '')
            xlabel(app.fig2, '')
            ylabel(app.fig2, '')
            app.fig4 = uiaxes(app.RightPanel);
            title(app.fig4, '')
            xlabel(app.fig4, '')
            ylabel(app.fig4, '')
            app.bLabel = uilabel(app.RightPanel);
            app.bLabel.Text = '(b)';
            app.dLabel = uilabel(app.RightPanel);
            app.dLabel.Text = '(d)';
            app.aLabel = uilabel(app.RightPanel);
            app.aLabel.Text = '(a)';
            app.cLabel = uilabel(app.RightPanel);
            app.cLabel.Text = '(c)';
            app.VisualizationPanelLabel = uilabel(app.RightPanel);
            app.VisualizationPanelLabel.Text = 'Visualization Panel';
            app.fig1 = uiaxes(app.RightPanel);
            title(app.fig1, '')
            xlabel(app.fig1, '')
            ylabel(app.fig1, '')
            app.fig3 = uiaxes(app.RightPanel);
            title(app.fig3, '')
            xlabel(app.fig3, '')
            ylabel(app.fig3, '')
            % Show the figure after all components are created
            app.Multi_Agent_Simulation.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multi_agent_simulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Multi_Agent_Simulation)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Multi_Agent_Simulation)
        end
    end
end